classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CalculateFitPotentialTab        matlab.ui.container.Tab
        Panel_12                        matlab.ui.container.Panel
        Panel_13                        matlab.ui.container.Panel
        Panel_19                        matlab.ui.container.Panel
        kguessEditField                 matlab.ui.control.NumericEditField
        kguessEditFieldLabel            matlab.ui.control.Label
        aguessEditField                 matlab.ui.control.NumericEditField
        aguessEditFieldLabel            matlab.ui.control.Label
        maxkEditField                   matlab.ui.control.NumericEditField
        maxkEditFieldLabel              matlab.ui.control.Label
        minkEditField                   matlab.ui.control.NumericEditField
        minkEditFieldLabel              matlab.ui.control.Label
        maxaEditField                   matlab.ui.control.NumericEditField
        maxaEditFieldLabel              matlab.ui.control.Label
        minaEditField                   matlab.ui.control.NumericEditField
        minaEditFieldLabel              matlab.ui.control.Label
        SpecifyPowerLawParametersLabel  matlab.ui.control.Label
        Panel_15                        matlab.ui.container.Panel
        sigmaguessEditField_2           matlab.ui.control.NumericEditField
        sigmaguessEditField_2Label      matlab.ui.control.Label
        welldepthguessEditField_2       matlab.ui.control.NumericEditField
        welldepthguessEditField_2Label  matlab.ui.control.Label
        maxsigmaEditField_2             matlab.ui.control.NumericEditField
        maxsigmaEditField_2Label        matlab.ui.control.Label
        minsigmaEditField_2             matlab.ui.control.NumericEditField
        minsigmaEditField_2Label        matlab.ui.control.Label
        maxwelldepthEditField_2         matlab.ui.control.NumericEditField
        maxwelldepthEditField_2Label    matlab.ui.control.Label
        minwelldepthEditField_2         matlab.ui.control.NumericEditField
        minwelldepthEditField_2Label    matlab.ui.control.Label
        SpecifyLJParametersLabel_3      matlab.ui.control.Label
        Panel_14                        matlab.ui.container.Panel
        minZ2EditField_2                matlab.ui.control.NumericEditField
        minZ2EditField_2Label           matlab.ui.control.Label
        maxZ2EditField_2                matlab.ui.control.NumericEditField
        maxZ2EditField_2Label           matlab.ui.control.Label
        Z1EditField_3                   matlab.ui.control.NumericEditField
        Z1EditField_3Label              matlab.ui.control.Label
        SpecifyCoulombZBLParametersLabel  matlab.ui.control.Label
        SelectedPathEditField_14        matlab.ui.control.EditField
        SelectedPathEditField_14Label   matlab.ui.control.Label
        SelectPotentialFileButton_2     matlab.ui.control.Button
        maxrEditField_3                 matlab.ui.control.NumericEditField
        maxrEditField_3Label            matlab.ui.control.Label
        minrEditField_3                 matlab.ui.control.NumericEditField
        minrEditField_3Label            matlab.ui.control.Label
        toleranceEditField_2            matlab.ui.control.NumericEditField
        toleranceEditField_2Label       matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        Panel_18                        matlab.ui.container.Panel
        kEditField                      matlab.ui.control.NumericEditField
        kAngstrom1Label                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Z2EditField                     matlab.ui.control.NumericEditField
        Z2EditFieldLabel                matlab.ui.control.Label
        Z1EditField                     matlab.ui.control.NumericEditField
        Z1EditFieldLabel                matlab.ui.control.Label
        rstepEditField                  matlab.ui.control.NumericEditField
        rstepEditFieldLabel             matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SigmaEditField                  matlab.ui.control.NumericEditField
        SigmaEditFieldLabel             matlab.ui.control.Label
        WellDepthEditField              matlab.ui.control.NumericEditField
        WellDepthEditFieldLabel         matlab.ui.control.Label
        minrEditField                   matlab.ui.control.NumericEditField
        minrEditFieldLabel              matlab.ui.control.Label
        maxrEditField                   matlab.ui.control.NumericEditField
        maxrEditFieldLabel              matlab.ui.control.Label
        SelectedActionDropDown          matlab.ui.control.DropDown
        SelectedActionDropDownLabel     matlab.ui.control.Label
        FitPotentialTypeDropDown        matlab.ui.control.DropDown
        FitPotentialTypeDropDownLabel   matlab.ui.control.Label
        SelectedPathEditField_4         matlab.ui.control.EditField
        SelectedPathEditField_4Label    matlab.ui.control.Label
        SelectInputFileButton           matlab.ui.control.Button
        OrSelectInputFileLabel          matlab.ui.control.Label
        SpecifyWheretoSaveOutputLabel   matlab.ui.control.Label
        SelectedPathEditField_3         matlab.ui.control.EditField
        SelectedPathEditField_3Label    matlab.ui.control.Label
        SelectOutputPathButton          matlab.ui.control.Button
        SpecifyWheretoSaveInputFileLabel  matlab.ui.control.Label
        SelectedPathEditField_2         matlab.ui.control.EditField
        SelectedPathEditField_2Label    matlab.ui.control.Label
        SelectInputPathButton           matlab.ui.control.Button
        ExecuteButton                   matlab.ui.control.Button
        WriteInputFileButton            matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        LINEARTab                       matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        LOGTab                          matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        CalculateScatteringIntegralsTab  matlab.ui.container.Tab
        IntegralTypeDropDown            matlab.ui.control.DropDown
        IntegralTypeDropDownLabel       matlab.ui.control.Label
        SelectPathButton_2              matlab.ui.control.Button
        SelectedPathEditField_6         matlab.ui.control.EditField
        SelectedPathEditField_6Label    matlab.ui.control.Label
        SpecifyWheretoSaveInputFileLabel_2  matlab.ui.control.Label
        SelectedFileEditField           matlab.ui.control.EditField
        SelectedFileEditFieldLabel      matlab.ui.control.Label
        SelectScatteringInputFileButton  matlab.ui.control.Button
        OrselectyourownscatteringinputfileLabel  matlab.ui.control.Label
        WriteScatteringInputFileButton  matlab.ui.control.Button
        SelectedPathEditField_5         matlab.ui.control.EditField
        SelectedPathEditField_5Label    matlab.ui.control.Label
        SpecifyWheretoSaveDifferentialScatteringDataLabel  matlab.ui.control.Label
        SelectPathButton                matlab.ui.control.Button
        TabGroup2_3                     matlab.ui.container.TabGroup
        DistofClosestApproachTab        matlab.ui.container.Tab
        UIAxes_7                        matlab.ui.control.UIAxes
        ScatteringTab                   matlab.ui.container.Tab
        UIAxes_5                        matlab.ui.control.UIAxes
        ImpactParameterTab              matlab.ui.container.Tab
        UIAxes_6                        matlab.ui.control.UIAxes
        ExecuteButton_3                 matlab.ui.control.Button
        Panel_7                         matlab.ui.container.Panel
        Panel_16                        matlab.ui.container.Panel
        maxRminEditField                matlab.ui.control.NumericEditField
        maxRminEditFieldLabel           matlab.ui.control.Label
        minRminEditField                matlab.ui.control.NumericEditField
        minRminEditFieldLabel           matlab.ui.control.Label
        SpecifyRootFindingRangeforRminLabel  matlab.ui.control.Label
        bstepAngEditField               matlab.ui.control.NumericEditField
        bstepAngEditFieldLabel          matlab.ui.control.Label
        bmaxAngEditField                matlab.ui.control.NumericEditField
        bmaxAngEditFieldLabel           matlab.ui.control.Label
        bminAngEditField                matlab.ui.control.NumericEditField
        bminAngEditFieldLabel           matlab.ui.control.Label
        SelectedPathEditField_15        matlab.ui.control.EditField
        SelectedPathEditField_15Label   matlab.ui.control.Label
        SelectFileButton                matlab.ui.control.Button
        SpecifyFitOutputFileLabel       matlab.ui.control.Label
        Panel_17                        matlab.ui.container.Panel
        thetaminradEditField            matlab.ui.control.NumericEditField
        thetaminradEditFieldLabel       matlab.ui.control.Label
        thetastepradEditField           matlab.ui.control.NumericEditField
        thetastepradEditFieldLabel      matlab.ui.control.Label
        thetamaxradEditField            matlab.ui.control.NumericEditField
        thetamaxradEditFieldLabel       matlab.ui.control.Label
        z2EditField                     matlab.ui.control.NumericEditField
        z2EditFieldLabel                matlab.ui.control.Label
        z1EditField                     matlab.ui.control.NumericEditField
        z1EditFieldLabel                matlab.ui.control.Label
        EstepeVEditField                matlab.ui.control.NumericEditField
        EstepeVEditFieldLabel           matlab.ui.control.Label
        maxEeVEditField                 matlab.ui.control.NumericEditField
        maxEeVEditFieldLabel            matlab.ui.control.Label
        ScatteringIntegralsLabel        matlab.ui.control.Label
        minEeVEditField                 matlab.ui.control.NumericEditField
        minEeVEditFieldLabel            matlab.ui.control.Label
        CalculateTransportCrossSectionsTab  matlab.ui.container.Tab
        IntegralTypeDropDown_2          matlab.ui.control.DropDown
        IntegralTypeDropDown_2Label     matlab.ui.control.Label
        SelectedPathEditField_8         matlab.ui.control.EditField
        SelectedPathEditField_8Label    matlab.ui.control.Label
        SelectedFileEditField_2         matlab.ui.control.EditField
        SelectedFileEditField_2Label    matlab.ui.control.Label
        SelectedPathEditField_7         matlab.ui.control.EditField
        SelectedPathEditField_7Label    matlab.ui.control.Label
        SelectPathButton_4              matlab.ui.control.Button
        SpecifyWheretoSaveInputFileLabel_3  matlab.ui.control.Label
        SelectTransportInputFileButton  matlab.ui.control.Button
        OrselectyourowntransportinputfileLabel  matlab.ui.control.Label
        WriteTransportInputFileButton   matlab.ui.control.Button
        SpecifyWheretoSaveTransportCrossSectionDataLabel  matlab.ui.control.Label
        SelectPathButton_3              matlab.ui.control.Button
        ExecuteButton_4                 matlab.ui.control.Button
        Panel_8                         matlab.ui.container.Panel
        Panel_21                        matlab.ui.container.Panel
        SelectedPathEditField_16        matlab.ui.control.EditField
        SelectedPathEditField_16Label   matlab.ui.control.Label
        SelectPathButton_7              matlab.ui.control.Button
        SpecifyImpactParamvsScatterAngleDataFolderLabel  matlab.ui.control.Label
        Panel_20                        matlab.ui.container.Panel
        welldeptheVEditField            matlab.ui.control.NumericEditField
        welldeptheVEditFieldLabel       matlab.ui.control.Label
        EstepeVEditField_2              matlab.ui.control.NumericEditField
        EstepeVEditField_2Label         matlab.ui.control.Label
        maxEeVEditField_2               matlab.ui.control.NumericEditField
        maxEeVEditField_2Label          matlab.ui.control.Label
        TransportCrossSectionLabel      matlab.ui.control.Label
        minEeVEditField_2               matlab.ui.control.NumericEditField
        minEeVEditField_2Label          matlab.ui.control.Label
        TabGroup2_4                     matlab.ui.container.TabGroup
        DiffusionCrossSectionTab        matlab.ui.container.Tab
        UIAxes_8                        matlab.ui.control.UIAxes
        ViscosityCrossSectionTab        matlab.ui.container.Tab
        UIAxes_9                        matlab.ui.control.UIAxes
        CalcuateTransportCoefficientsTab  matlab.ui.container.Tab
        Panel_11                        matlab.ui.container.Panel
        SelectedFileEditField_4         matlab.ui.control.EditField
        SelectedFileEditField_4Label    matlab.ui.control.Label
        SelectCrossSectionDataButton    matlab.ui.control.Button
        IntegrationTypeDropDown         matlab.ui.control.DropDown
        IntegrationTypeDropDownLabel    matlab.ui.control.Label
        TransportCoefficientDropDown    matlab.ui.control.DropDown
        TransportCoefficientDropDownLabel  matlab.ui.control.Label
        TabGroup2_5                     matlab.ui.container.TabGroup
        LINEARTab_3                     matlab.ui.container.Tab
        UIAxes_10                       matlab.ui.control.UIAxes
        LOGTab_3                        matlab.ui.container.Tab
        UIAxes_11                       matlab.ui.control.UIAxes
        SelectedPathEditField_10        matlab.ui.control.EditField
        SelectedPathEditField_10Label   matlab.ui.control.Label
        SelectedFileEditField_3         matlab.ui.control.EditField
        SelectedFileEditField_3Label    matlab.ui.control.Label
        SelectedPathEditField_9         matlab.ui.control.EditField
        SelectedPathEditField_9Label    matlab.ui.control.Label
        SelectPathButton_6              matlab.ui.control.Button
        SpecifyWheretoSaveInputFileLabel_4  matlab.ui.control.Label
        SelectTransportInputFileButton_2  matlab.ui.control.Button
        OrselectyourowntransportinputfileLabel_2  matlab.ui.control.Label
        WriteTransportInputFileButton_2  matlab.ui.control.Button
        SpecifyWheretoSaveTransportCoefficientDataLabel  matlab.ui.control.Label
        SelectPathButton_5              matlab.ui.control.Button
        ExecuteButton_5                 matlab.ui.control.Button
        Panel_9                         matlab.ui.container.Panel
        dangstromEditField              matlab.ui.control.NumericEditField
        dangstromEditFieldLabel         matlab.ui.control.Label
        Panel_10                        matlab.ui.container.Panel
        PressurebarEditField            matlab.ui.control.NumericEditField
        PressurebarEditFieldLabel       matlab.ui.control.Label
        m2amuEditField                  matlab.ui.control.NumericEditField
        m2amuEditFieldLabel             matlab.ui.control.Label
        m1amuEditField                  matlab.ui.control.NumericEditField
        m1amuEditFieldLabel             matlab.ui.control.Label
        welldepthKEditField             matlab.ui.control.NumericEditField
        welldepthKEditFieldLabel        matlab.ui.control.Label
        TstepKEditField                 matlab.ui.control.NumericEditField
        TstepKEditFieldLabel            matlab.ui.control.Label
        maxTKEditField                  matlab.ui.control.NumericEditField
        maxTKEditFieldLabel             matlab.ui.control.Label
        minTKEditField                  matlab.ui.control.NumericEditField
        minTKEditFieldLabel             matlab.ui.control.Label
        CalcuateDSMCCoefficientsTab     matlab.ui.container.Tab
    end

    %these are properties that I utilize across different callbacks
    properties (Access = public)
        matfile % Description
        directory
        DataSetName
        usrnumber
        selpath
        selpath2
        selpath3
        selpath4
        selpath5
        selpath6
        selpath7
        selpath8
        selpath9
        selpath10
        selpath11
        selpath12
        selpath13
        selpath14
        selpath15
        selpath16
        selpath17
        selpath18
        minE
        maxE
        Estep
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.FontName="Courier";
            app.TabGroup.FontWeight = 'bold';
            app.TabGroup2.FontName="Courier";
            app.TabGroup2.FontWeight = 'bold';
            app.Panel.Visible='off';
            app.Panel_2.Visible='off';
            app.Panel_10.Visible='off';
            app.Panel_11.Visible='off';
            app.Panel_6.Visible = 'off';
            app.Panel_13.Visible = 'off';
            app.Panel_14.Visible = 'off';
            app.Panel_15.Visible = 'off';
            app.Panel_17.Visible = 'off';
            app.Panel_16.Visible = 'off';
            app.Panel_18.Visible = 'off';
            app.Panel_19.Visible = 'off';
            app.Panel_20.Visible = 'off';
            app.Panel_21.Visible = 'off';

        end

        % Clicked callback: FitPotentialTypeDropDown
        function FitPotentialTypeDropDownClicked(app, event)
            if strcmp(app.SelectedActionDropDown.Value,'Calculate Potential')
                if strcmp(app.FitPotentialTypeDropDown.Value,'Coulomb') ...
                    | strcmp(app.FitPotentialTypeDropDown.Value,'ZBL')
                    app.Panel.Visible='on'; % Set the visibility of the panel to 'on'
                    app.Panel_2.Visible='off';
                    app.Panel_18.Visible = 'off';
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'Lennard-Jones') 
                    app.Panel_2.Visible='on'; % Set the visibility of the panel to 'on'
                    app.Panel.Visible='off';
                    app.Panel_18.Visible = 'off';
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'Power Law')
                    app.Panel_18.Visible = 'on';
                    app.Panel.Visible = 'off';
                    app.Panel_2.Visible = 'off';

                else 
                    app.Panel.Visible='off';
                    app.Panel_2.Visible='off';
                    app.Panel_18.Visible = 'off';
                end
            
            elseif strcmp(app.SelectedActionDropDown.Value,'Fit Potential')
                if strcmp(app.FitPotentialTypeDropDown.Value,'Coulomb') ...
                    | strcmp(app.FitPotentialTypeDropDown.Value,'ZBL')
                    app.Panel_14.Visible='on'; % Set the visibility of the panel to 'on'
                    app.Panel_15.Visible='off';
                    app.Panel_19.Visible = 'off';
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'Lennard-Jones')
                    app.Panel_15.Visible='on'; % Set the visibility of the panel to 'on'
                    app.Panel_14.Visible='off';
                    app.Panel_19.Visible = 'off';
                elseif strcmp(app.FitPotentialTypeDropDown.Value,'Power Law')
                    app.Panel_19.Visible = 'on';
                    app.Panel_14.Visible='off';
                    app.Panel_15.Visible='off';
                else 
                    app.Panel_14.Visible='off';
                    app.Panel_15.Visible='off';
                    app.Panel_19.Visible = 'off';
                end
            end
            
     
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            %executes run_calcpotential, plots data, pushes data
            %location to next tab
            addpath('../src')
            addpath('./src')
            if strcmp(app.SelectedActionDropDown.Value,'Calculate Potential')
                y = run_calcpotential(app.SelectedPathEditField_4.Value,...
                app.SelectedPathEditField_3.Value);
                t = readmatrix(y);
                xvals = t(:,1);
                yvals = t(:,2);
                plot(app.UIAxes,xvals,yvals,'-o',MarkerFaceColor='b');
                plot(app.UIAxes_2,xvals,yvals,'-o',MarkerFaceColor='b');
                yscale(app.UIAxes_2,'log');
                xscale(app.UIAxes_2,'linear');
                title(app.UIAxes,'Interatomic Potential');
                title(app.UIAxes_2,'Interatomic Potential');
                %app.SelectedPathEditField.Value = y;
            elseif strcmp(app.SelectedActionDropDown.Value,'Fit Potential')
                run(app.SelectedPathEditField_4.Value);
                datalocation = app.SelectedPathEditField_14.Value;
                t = readmatrix(datalocation);
                myfit = run_fitpotential(app.SelectedPathEditField_4.Value,...
                    app.SelectedPathEditField_14.Value);
                coefs = coeffvalues(myfit);
                outputfilename  = [app.SelectedPathEditField_3.Value '/fitoutputfile.m'];
                outputfile=fopen(outputfilename,'w');
                fprintf(outputfile,'Potential_Type = "%s";\n',app.FitPotentialTypeDropDown.Value);
                fprintf(outputfile, 'minR = %f;\n',minR);
                fprintf(outputfile, 'maxR = %f;\n',maxR);
                fprintf(outputfile, 'Tol = %e;\n',tol);
                if strcmp(app.FitPotentialTypeDropDown.Value, "Coulomb") ...
                        | strcmp(app.FitPotentialTypeDropDown.Value, "ZBL")
                    fprintf(outputfile, 'Z1 = %i;\n',Z1);
                    fprintf(outputfile,'z2_param = %s;\n',coefs);
                    fprintf(outputfile, 'minZ2 = %i;\n',minZ2);
                    fprintf(outputfile, 'maxZ2 = %i;\n',maxZ2);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Lennard-Jones")
                    fprintf(outputfile,'eps_param = %s;\n',coefs(1));
                    fprintf(outputfile,'sigma_param = %s;\n',coefs(2));
                    fprintf(outputfile, 'min_eps = %f;\n',min_eps);
                    fprintf(outputfile, 'max_eps = %f;\n',max_eps);
                    fprintf(outputfile, 'min_sigma = %f;\n',min_sigma);
                    fprintf(outputfile, 'max_sigma = %f;\n',max_sigma);
                    fprintf(outputfile, 'eps_start = %f;\n',eps_start);
                    fprintf(outputfile, 'sigma_start = %f;\n',sigma_start);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Power Law")
                    fprintf(outputfile,'a_param = %s;\n',coefs(1));
                    fprintf(outputfile,'k_param = %s;\n',coefs(2));
                    fprintf(outputfile, 'min_a = %f;\n',min_a);
                    fprintf(outputfile, 'max_a = %f;\n',max_a);
                    fprintf(outputfile, 'min_k = %f;\n',min_k);
                    fprintf(outputfile, 'max_k = %f;\n',max_k);
                    fprintf(outputfile, 'a_start = %f;\n',a_start);
                    fprintf(outputfile, 'k_start = %f;\n',k_start);
                end
                fclose(outputfile);
                excludex = ((t(:,1) < minR) | (t(:,1) > maxR));
                xvals = t(:,1);
                yvals = t(:,2);
                xvals(excludex) = [];
                yvals(excludex) = [];
               
                plot(app.UIAxes,myfit,xvals,yvals);
                title(app.UIAxes,'Fitted Interatomic Potential');
                % plot(app.UIAxes_4,myfit,xvals,yvals);
                % yscale(app.UIAxes_4,'log');
                % xscale(app.UIAxes_4,'linear');
            end


        end

        % Button pushed function: WriteInputFileButton
        function WriteInputFileButtonPushed2(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            if strcmp(app.SelectedActionDropDown.Value,'Calculate Potential')
                minr = app.minrEditField.Value;
                maxr = app.maxrEditField.Value;
                rstep = app.rstepEditField.Value;
                z1 = app.Z1EditField.Value;
                z2 = app.Z2EditField.Value;
                a = app.aEditField.Value;
                k = app.kEditField.Value;
                well_eps = app.WellDepthEditField.Value;
                sigma = app.SigmaEditField.Value;
                filename  = [app.SelectedPathEditField_2.Value '/potentialinputfile.m'];
                inputfile=fopen(filename,'w');
                fprintf(inputfile,'Potential_Type = "%s";\n',app.FitPotentialTypeDropDown.Value);
                fprintf(inputfile, 'minR = %f;\n',minr);
                fprintf(inputfile, 'maxR = %f;\n',maxr);
                fprintf(inputfile, 'Rstep = %f;\n',rstep);
                if strcmp(app.FitPotentialTypeDropDown.Value,"Coulomb") | ...
                    strcmp(app.FitPotentialTypeDropDown.Value, "ZBL")
                    fprintf(inputfile, 'Z1 = %i;\n',z1);
                    fprintf(inputfile, 'Z2 = %i;\n',z2);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Lennard-Jones")
                    fprintf(inputfile, 'eps_well = %f;\n',well_eps);
                    fprintf(inputfile, 'sigma = %i;\n',sigma);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Power Law")
                    fprintf(inputfile, 'a = %f;\n',a);
                    fprintf(inputfile, 'k = %f;\n',k);
                end
                fclose(inputfile);
                
            elseif strcmp(app.SelectedActionDropDown.Value,'Fit Potential')
                z1 = app.Z1EditField_3.Value;
                minr = app.minrEditField_3.Value;
                maxr = app.maxrEditField_3.Value;
                tol = app.toleranceEditField_2.Value;
                minz2 = app.minZ2EditField_2.Value;
                maxz2 = app.maxZ2EditField_2.Value;
                min_eps = app.minwelldepthEditField_2.Value;
                max_eps = app.maxwelldepthEditField_2.Value;
                min_sigma = app.minsigmaEditField_2.Value;
                max_sigma = app.maxsigmaEditField_2.Value;
                eps_start = app.welldepthguessEditField_2.Value;
                sigma_start = app.sigmaguessEditField_2.Value;
                k_start = app.kguessEditField.Value;
                a_start = app.aguessEditField.Value;
                maxa = app.maxaEditField.Value;
                mina = app.minaEditField.Value;
                maxk = app.maxkEditField.Value;
                mink = app.minkEditField.Value;
                filename  = [app.SelectedPathEditField_2.Value '/fitinputfile.m'];
                inputfile=fopen(filename,'w');
                fprintf(inputfile,'Potential_Type = "%s";\n',app.FitPotentialTypeDropDown.Value);
                fprintf(inputfile, 'minR = %f;\n',minr);
                fprintf(inputfile, 'maxR = %f;\n',maxr);
                fprintf(inputfile,'tol = %e;\n',tol);
                if strcmp(app.FitPotentialTypeDropDown.Value, 'Coulomb') ...
                        | strcmp(app.FitPotentialTypeDropDown.Value,'ZBL')
                    fprintf(inputfile, 'Z1 = %i;\n',z1);
                    fprintf(inputfile, 'minZ2 = %i;\n',minz2);
                    fprintf(inputfile, 'maxZ2 = %i;\n',maxz2);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Lennard-Jones")
                    fprintf(inputfile, 'min_eps = %f;\n',min_eps);
                    fprintf(inputfile, 'max_eps = %f;\n',max_eps);
                    fprintf(inputfile, 'min_sigma = %f;\n',min_sigma);
                    fprintf(inputfile, 'max_sigma = %f;\n',max_sigma);
                    fprintf(inputfile, 'eps_start = %f;\n',eps_start);
                    fprintf(inputfile, 'sigma_start = %f;\n',sigma_start);
                elseif strcmp(app.FitPotentialTypeDropDown.Value,"Power Law")
                    fprintf(inputfile, 'min_a = %f;\n',mina);
                    fprintf(inputfile, 'max_a = %f;\n',maxa);
                    fprintf(inputfile, 'min_k = %f;\n',mink);
                    fprintf(inputfile, 'max_k = %f;\n',maxk);
                    fprintf(inputfile, 'k_start = %f;\n', k_start);
                    fprintf(inputfile, 'a_start = %f;\n', a_start);
                end
                fclose(inputfile);
            end
         app.SelectedPathEditField_4.Value = filename;
        end

        % Button pushed function: SelectOutputPathButton
        function SelectOutputPathButtonPushed2(app, event)
            app.selpath3  = uigetdir;
            app.SelectedPathEditField_3.Value = app.selpath3;
        end

        % Button pushed function: SelectInputFileButton
        function SelectInputFileButtonPushed2(app, event)
            %allows user to select their own input file
            [baseName, folder] = uigetfile();
            app.selpath4 = fullfile(folder, baseName);
            app.SelectedPathEditField_4.Value = app.selpath4;
        end

        % Button pushed function: SelectInputPathButton
        function SelectInputPathButtonPushed(app, event)
            app.selpath5  = uigetdir;
            app.SelectedPathEditField_2.Value = app.selpath5;
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            app.selpath6  = uigetdir;
            app.SelectedPathEditField_5.Value = app.selpath6;
        end

        % Button pushed function: ExecuteButton_3
        function ExecuteButton_3Pushed(app, event)

            addpath('./src')
            addpath('../src')
            y = run_scatteringintegrals(app.SelectedFileEditField.Value,...
                app.SelectedPathEditField_5.Value);
            run(app.SelectedFileEditField.Value);
            Evals = minE:Estep:maxE;
            hold(app.UIAxes_5,"on");
            hold(app.UIAxes_6,"on");
            hold(app.UIAxes_7,"on");
            cla(app.UIAxes_5);
            cla(app.UIAxes_6);
            cla(app.UIAxes_7);
            axlegend = {};
            for E = Evals
                axlegend  = [axlegend sprintf('%f eV',E)];
                disp(axlegend);
                docadatapath  = [y sprintf('/docadata/docadata_%f.csv',E)];
                tdoca = readmatrix(docadatapath);
                xvals3 = tdoca(:,1);
                yvals3 = tdoca(:,2);
                plot(app.UIAxes_7,xvals3,yvals3,'-o');
                yscale(app.UIAxes_7,'log');
                xscale(app.UIAxes_7,'log');
                if strcmp(app.IntegralTypeDropDown.Value,'Exact Coulomb')
                    difscatterdatapath  = [y sprintf('/difscatterdata/difscatterdata_%f.csv',E)];
                    impactdatapath  = [y sprintf('/impactparamdata/impactparamdata_%f.csv',E)];
                    tdif = readmatrix(difscatterdatapath);
                    timpact = readmatrix(impactdatapath);
                    xvals1 = tdif(:,1);
                    yvals1 = tdif(:,2);
                    xvals2 = timpact(:,1);
                    yvals2 = timpact(:,2);
                    plot(app.UIAxes_5,xvals1,yvals1,'-o');
                    plot(app.UIAxes_6,xvals2,yvals2,'-o');
                    title(app.UIAxes_5,'Differential Scattering Cross Section')
                    xlabel(app.UIAxes_5,'Scattering Angle (rad)')
                    ylabel(app.UIAxes_5,'Cross Section (Angstrom^2/sr)')
                elseif strcmp(app.IntegralTypeDropDown.Value,'Numerical')
                    scatterangdatapath = [y sprintf('/scatterangledata/scatterangledata_%f.csv',E)];
                    ttheta = readmatrix(scatterangdatapath);
                    xvals1 = ttheta(:,1);
                    yvals1 = ttheta(:,2);
                    plot(app.UIAxes_5,xvals1,yvals1,'-o');
                    title(app.UIAxes_5,'Scattering Angle')
                    xlabel(app.UIAxes_5,'Impact Parameter (Angstrom)')
                    ylabel(app.UIAxes_5,'Angle (rad)')
                end
            end
           legend(app.UIAxes_5,axlegend);
           legend(app.UIAxes_7,axlegend);
           legend(app.UIAxes_6,axlegend);
           
     
        end

        % Button pushed function: SelectScatteringInputFileButton
        function SelectScatteringInputFileButtonPushed(app, event)
            %allows user to select their own input file
            [baseName, folder] = uigetfile();
            app.selpath7 = fullfile(folder, baseName);
            app.SelectedFileEditField.Value = app.selpath7;
        end

        % Button pushed function: WriteScatteringInputFileButton
        function WriteScatteringInputFileButtonPushed(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            % and executes run_calcpotential, plots data, pushes data
            %location to next tab
            app.minE = app.minEeVEditField.Value;
            app.maxE = app.maxEeVEditField.Value;
            app.Estep = app.EstepeVEditField.Value;
            filename  = [app.SelectedPathEditField_6.Value '/scatterintinputfile.m'];
            inputfile=fopen(filename,'w');
            fprintf(inputfile,'inttype = "%s";\n',app.IntegralTypeDropDown.Value);
            fprintf(inputfile, 'minE = %f;\n',app.minE);
            fprintf(inputfile, 'maxE = %f;\n',app.maxE);
            fprintf(inputfile, 'Estep = %f;\n',app.Estep);
            if strcmp(app.IntegralTypeDropDown.Value,'Exact Coulomb')
                z1 = app.z1EditField.Value;            
                z2 = app.z2EditField.Value;
                theta_min = app.thetaminradEditField.Value;
                theta_max = app.thetamaxradEditField.Value;
                theta_step = app.thetastepradEditField.Value;
                fprintf(inputfile, 'Z1 = %i;\n',z1);
                fprintf(inputfile,'Z2 = %i;\n',z2);
                fprintf(inputfile,'theta_min = %f; \n',theta_min);
                fprintf(inputfile,'theta_max = %f; \n',theta_max);
                fprintf(inputfile,'theta_step = %f; \n',theta_step);
            elseif strcmp(app.IntegralTypeDropDown.Value,'Numerical')
                bmin = app.bminAngEditField.Value;
                bmax = app.bmaxAngEditField.Value;
                bstep = app.bstepAngEditField.Value;
                minroot = app.minRminEditField.Value;
                maxroot = app.maxRminEditField.Value;
                fitfile = app.SelectedPathEditField_15.Value;
                fprintf(inputfile,'fitfile = "%s";\n',fitfile);
                fprintf(inputfile,'bmin = %f; \n',bmin);
                fprintf(inputfile,'bmax = %f; \n',bmax);
                fprintf(inputfile,'bstep = %f; \n',bstep);
                fprintf(inputfile,'minroot = %f; \n',minroot);
                fprintf(inputfile,'maxroot = %f; \n',maxroot);
            end
            fclose(inputfile);
            app.SelectedFileEditField.Value = filename;
        end

        % Button pushed function: SelectPathButton_2
        function SelectPathButton_2Pushed(app, event)
            app.selpath8  = uigetdir;
            app.SelectedPathEditField_6.Value = app.selpath8;
        end

        % Button pushed function: SelectPathButton_4
        function SelectPathButton_4Pushed(app, event)
            app.selpath9  = uigetdir;
            app.SelectedPathEditField_8.Value = app.selpath9;
        end

        % Button pushed function: WriteTransportInputFileButton
        function WriteTransportInputFileButtonPushed(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            % and executes run_transportCS, plots data, pushes data
            %location to next tab
            min_E = app.minEeVEditField_2.Value;
            max_E = app.maxEeVEditField_2.Value;
            E_step = app.EstepeVEditField_2.Value;
            filename  = [app.SelectedPathEditField_8.Value '/transportinputfile.m'];
            inputfile=fopen(filename,'w');
            fprintf(inputfile,'inttype = "%s";\n',app.IntegralTypeDropDown_2.Value);
            fprintf(inputfile, 'minE = %f;\n',min_E);
            fprintf(inputfile, 'maxE = %f;\n',max_E);
            fprintf(inputfile, 'Estep = %f;\n',E_step);
            if strcmp(app.IntegralTypeDropDown_2.Value,'Exact LJ')
                welldepth = app.welldeptheVEditField.Value;
                fprintf(inputfile,'welldepth = %f; \n',welldepth);


            elseif strcmp(app.IntegralTypeDropDown_2.Value,'Numerical')
                datafilepath = app.SelectedPathEditField_16.Value;
                fprintf(inputfile,'datafolder = "%s";\n',datafilepath);
            end
            fclose(inputfile);
            app.SelectedFileEditField_2.Value = filename;

        end

        % Button pushed function: SelectTransportInputFileButton
        function SelectTransportInputFileButtonPushed(app, event)
            %allows user to select their own input file
            [baseName, folder] = uigetfile();
            app.selpath10 = fullfile(folder, baseName);
            app.SelectedFileEditField_2.Value = app.selpath10;
        end

        % Button pushed function: SelectPathButton_3
        function SelectPathButton_3Pushed(app, event)
            app.selpath11  = uigetdir;
            app.SelectedPathEditField_7.Value = app.selpath11;
        end

        % Button pushed function: ExecuteButton_4
        function ExecuteButton_4Pushed(app, event)
            
            addpath('./src')
            addpath('../src')
            run_transportCS(app.SelectedFileEditField_2.Value,...
                app.SelectedPathEditField_7.Value);
            run(app.SelectedFileEditField_2.Value);
            diffusioncsdatapath  = [app.SelectedPathEditField_7.Value '/diffusioncsdata.csv'];
            viscositycsdatapath  = [app.SelectedPathEditField_7.Value '/viscositycsdata.csv'];
            tdiffusion = readmatrix(diffusioncsdatapath);
            tvisc = readmatrix(viscositycsdatapath);
            xvals1 = tdiffusion(:,1);
            yvals1 = tdiffusion(:,2);
            xvals2 = tvisc(:,1);
            yvals2 = tvisc(:,2);
            plot(app.UIAxes_8,xvals1,yvals1,'-o');
            plot(app.UIAxes_9,xvals2,yvals2,'-o');
            xlabel(app.UIAxes_8,'Energy');
            xlabel(app.UIAxes_9,'Energy');
            
            % xlim(app.UIAxes_8,[0,100]);
            % xlim(app.UIAxes_9,[0,100]);
            yscale(app.UIAxes_8,'log');
            xscale(app.UIAxes_8,'log');
            yscale(app.UIAxes_9,'log');
            xscale(app.UIAxes_9,'log');


        end

        % Clicked callback: TransportCoefficientDropDown
        function TransportCoefficientDropDownClicked(app, event)
            if strcmp(app.TransportCoefficientDropDown.Value,'Self-Diffusion')
                app.Panel_10.Visible='on'; % Set the visibility of the panel to 'on'
            else 
                app.Panel_10.Visible='off';
            end
        end

        % Clicked callback: IntegrationTypeDropDown
        function IntegrationTypeDropDownClicked(app, event)
            if strcmp(app.IntegrationTypeDropDown.Value,'Exact')
                app.Panel_11.Visible='off'; % Set the visibility of the panel to 'off'
            elseif strcmp(app.IntegrationTypeDropDown.Value,'Trapezoidal') 
                app.Panel_11.Visible='on';
            else
                app.Panel_11.Visible='off';
            end
        end

        % Button pushed function: SelectCrossSectionDataButton
        function SelectCrossSectionDataButtonPushed(app, event)
            [baseName, folder] = uigetfile('*.csv');
            app.selpath12 = fullfile(folder, baseName);
            app.SelectedFileEditField_4.Value = app.selpath12;
        end

        % Button pushed function: SelectPathButton_6
        function SelectPathButton_6Pushed(app, event)
            app.selpath13 = uigetdir;
            app.SelectedPathEditField_10.Value = app.selpath13;
        end

        % Button pushed function: SelectTransportInputFileButton_2
        function SelectTransportInputFileButton_2Pushed(app, event)
            [baseName, folder] = uigetfile();
            app.selpath14 = fullfile(folder, baseName);
            app.SelectedFileEditField_3.Value = app.selpath14;
        end

        % Button pushed function: SelectPathButton_5
        function SelectPathButton_5Pushed(app, event)
            app.selpath15 = uigetdir;
            app.SelectedPathEditField_9.Value = app.selpath15;
        end

        % Button pushed function: WriteTransportInputFileButton_2
        function WriteTransportInputFileButton_2Pushed(app, event)
            minT = app.minTKEditField.Value;
            maxT = app.maxTKEditField.Value;
            Tstep = app.TstepKEditField.Value;
            welldepth = app.welldepthKEditField.Value;
            m1 = app.m1amuEditField.Value;
            m2 = app.m2amuEditField.Value;
            P = app.PressurebarEditField.Value;
            d = app.dangstromEditField.Value;
            datafile = app.SelectedFileEditField_4.Value;
            coeftype = app.TransportCoefficientDropDown.Value;
            inttype = app.IntegrationTypeDropDown.Value;
            filename  = [app.selpath13 '/transportcoefinputfile.m'];
            inputfile=fopen(filename,'w');
            %fprintf(inputfile,'Potential_Type = "%s";\n',app.FitTypeDropDown.Value);
            fprintf(inputfile, 'coeftype = "%s";\n',coeftype);
            fprintf(inputfile, 'inttype = "%s";\n',inttype);
            fprintf(inputfile, 'minT = %f;\n',minT);
            fprintf(inputfile, 'maxT = %f;\n',maxT);
            fprintf(inputfile, 'Tstep = %f;\n', Tstep);
            fprintf(inputfile,'welldepth = %f; \n',welldepth);
            fprintf(inputfile,'m1 = %f; \n',m1);
            fprintf(inputfile,'m2 = %f; \n',m2);
            fprintf(inputfile,'d = %f; \n',d);
            if strcmp(app.TransportCoefficientDropDown.Value,"Self-Diffusion")
                fprintf(inputfile,'p = %f; \n',P);
            end
            if strcmp(app.IntegrationTypeDropDown.Value,"Trapezoidal")
                fprintf(inputfile,'datafile = "%s"; \n',datafile);
            else
                fprintf(inputfile,'datafile = "%s"; \n','dummyfile');
            end
            fclose(inputfile);
            app.SelectedFileEditField_3.Value = filename;
        end

        % Button pushed function: ExecuteButton_5
        function ExecuteButton_5Pushed(app, event)
            addpath('./src')
            addpath('../src')
            y = run_transportcoefs(app.SelectedFileEditField_3.Value,app.SelectedPathEditField_9.Value);
            run(app.SelectedFileEditField_3.Value);
            t = readmatrix(y);
            tvals = t(:,1);
            coefvals = t(:,2);
            plot(app.UIAxes_10,tvals,coefvals);
            plot(app.UIAxes_11,tvals,coefvals);
            yscale(app.UIAxes_11,'log');
            xscale(app.UIAxes_11,'linear');
            if strcmp(coeftype,'Self-Diffusion')
                title(app.UIAxes_10,'Self-Diffusion Coefficient')
                title(app.UIAxes_11,'Self-Diffusion Coefficient')
            elseif strcmp(coeftype,'Viscosity')
                title(app.UIAxes_10,'Viscosity Coefficient')
                title(app.UIAxes_11,'Viscosity Coefficient')
            else
                disp('Title assignment failed')
            end


        end

        % Clicked callback: SelectedActionDropDown
        function SelectedActionDropDownClicked(app, event)
            if strcmp(app.SelectedActionDropDown.Value,'Calculate Potential') 
                app.Panel_6.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_13.Visible='off';
            elseif strcmp(app.SelectedActionDropDown.Value,'Fit Potential') 
                app.Panel_13.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_6.Visible='off';
            else 
                app.Panel_6.Visible='off';
                app.Panel_13.Visible='off';
            end
            
        end

        % Button pushed function: SelectPotentialFileButton_2
        function SelectPotentialFileButton_2Pushed(app, event)
            %allows you to select input file from directory
            [baseName, folder] = uigetfile('*.csv');
            selpath19  = fullfile(folder, baseName);
            app.SelectedPathEditField_14.Value = selpath19;
        end

        % Clicked callback: IntegralTypeDropDown
        function IntegralTypeDropDownClicked(app, event)
            if strcmp(app.IntegralTypeDropDown.Value,'Exact Coulomb') 
                app.Panel_17.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_16.Visible='off';
            elseif strcmp(app.IntegralTypeDropDown.Value,'Numerical') 
                app.Panel_16.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_17.Visible='off';
            else 
                app.Panel_17.Visible='off';
                app.Panel_16.Visible='off';
            end
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [baseName, folder] = uigetfile();
            app.SelectedPathEditField_15.Value = fullfile(folder, baseName);

        end

        % Button down function: UIAxes_5
        function UIAxes_5ButtonDown(app, event)
            
        end

        % Value changed function: minRminEditField
        function minRminEditFieldValueChanged(app, event)
            value = app.minRminEditField.Value;
            if value == 0
               disp('Change your min Rmin value. 0 will break the root finder.')
            end
        end

        % Button pushed function: SelectPathButton_7
        function SelectPathButton_7Pushed(app, event)
            datapath = uigetdir;
            app.SelectedPathEditField_16.Value = datapath;

        end

        % Clicked callback: IntegralTypeDropDown_2
        function IntegralTypeDropDown_2Clicked(app, event)
            if strcmp(app.IntegralTypeDropDown_2.Value,'Exact LJ') 
                app.Panel_20.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_21.Visible='off';
            elseif strcmp(app.IntegralTypeDropDown_2.Value,'Numerical') 
                app.Panel_21.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_20.Visible='off';
            else 
                app.Panel_21.Visible='off';
                app.Panel_20.Visible='off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalculateFitPotentialTab = uitab(app.TabGroup);
            app.CalculateFitPotentialTab.Title = 'Calculate/Fit Potential';
            app.TabGroup2 = uitabgroup(app.CalculateFitPotentialTab);
            app.LINEARTab = uitab(app.TabGroup2);
            app.LINEARTab.Title = 'LINEAR';
            app.UIAxes = uiaxes(app.LINEARTab);
            title(app.UIAxes, 'Interatomic Potential')
            xlabel(app.UIAxes, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes, 'Energy (eV)')
            zlabel(app.UIAxes, 'Z')
            app.LOGTab = uitab(app.TabGroup2);
            app.LOGTab.Title = 'LOG';
            app.UIAxes_2 = uiaxes(app.LOGTab);
            title(app.UIAxes_2, 'Interatomic Potential')
            xlabel(app.UIAxes_2, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes_2, 'Energy (eV)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XScale = 'log';
            app.UIAxes_2.YScale = 'log';
            app.WriteInputFileButton = uibutton(app.CalculateFitPotentialTab, 'push');
            app.WriteInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteInputFileButtonPushed2, true);
            app.WriteInputFileButton.Text = 'Write Input File';
            app.ExecuteButton = uibutton(app.CalculateFitPotentialTab, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.SelectInputPathButton = uibutton(app.CalculateFitPotentialTab, 'push');
            app.SelectInputPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputPathButtonPushed, true);
            app.SelectInputPathButton.Text = 'Select Input Path';
            app.SelectedPathEditField_2Label = uilabel(app.CalculateFitPotentialTab);
            app.SelectedPathEditField_2Label.Text = 'Selected Path';
            app.SelectedPathEditField_2 = uieditfield(app.CalculateFitPotentialTab, 'text');
            app.SpecifyWheretoSaveInputFileLabel = uilabel(app.CalculateFitPotentialTab);
            app.SpecifyWheretoSaveInputFileLabel.Text = 'Specify Where to Save Input File';
            app.SelectOutputPathButton = uibutton(app.CalculateFitPotentialTab, 'push');
            app.SelectOutputPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputPathButtonPushed2, true);
            app.SelectOutputPathButton.Text = 'Select Output Path';
            app.SelectedPathEditField_3Label = uilabel(app.CalculateFitPotentialTab);
            app.SelectedPathEditField_3Label.Text = 'Selected Path';
            app.SelectedPathEditField_3 = uieditfield(app.CalculateFitPotentialTab, 'text');
            app.SpecifyWheretoSaveOutputLabel = uilabel(app.CalculateFitPotentialTab);
            app.SpecifyWheretoSaveOutputLabel.Text = 'Specify Where to Save Output';
            app.OrSelectInputFileLabel = uilabel(app.CalculateFitPotentialTab);
            app.OrSelectInputFileLabel.Text = 'Or Select Input File';
            app.SelectInputFileButton = uibutton(app.CalculateFitPotentialTab, 'push');
            app.SelectInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputFileButtonPushed2, true);
            app.SelectInputFileButton.Text = 'Select Input File';
            app.SelectedPathEditField_4Label = uilabel(app.CalculateFitPotentialTab);
            app.SelectedPathEditField_4Label.Text = 'Selected Path';
            app.SelectedPathEditField_4 = uieditfield(app.CalculateFitPotentialTab, 'text');
            app.FitPotentialTypeDropDownLabel = uilabel(app.CalculateFitPotentialTab);
            app.FitPotentialTypeDropDownLabel.Text = 'Fit/Potential Type';
            app.FitPotentialTypeDropDown = uidropdown(app.CalculateFitPotentialTab);
            app.FitPotentialTypeDropDown.Items = {'Select Potential', 'Coulomb', 'Lennard-Jones', 'ZBL', 'Power Law'};
            app.FitPotentialTypeDropDown.ClickedFcn = createCallbackFcn(app, @FitPotentialTypeDropDownClicked, true);
            app.FitPotentialTypeDropDown.Value = 'Select Potential';
            app.SelectedActionDropDownLabel = uilabel(app.CalculateFitPotentialTab);
            app.SelectedActionDropDownLabel.Text = 'Selected Action';
            app.SelectedActionDropDown = uidropdown(app.CalculateFitPotentialTab);
            app.SelectedActionDropDown.Items = {'Select Action', 'Calculate Potential', 'Fit Potential'};
            app.SelectedActionDropDown.ClickedFcn = createCallbackFcn(app, @SelectedActionDropDownClicked, true);
            app.SelectedActionDropDown.Value = 'Select Action';
            app.Panel_12 = uipanel(app.CalculateFitPotentialTab);
            app.Panel_6 = uipanel(app.Panel_12);
            app.Panel_6.Visible = 'off';
            app.maxrEditFieldLabel = uilabel(app.Panel_6);
            app.maxrEditFieldLabel.Text = 'max r';
            app.maxrEditField = uieditfield(app.Panel_6, 'numeric');
            app.minrEditFieldLabel = uilabel(app.Panel_6);
            app.minrEditFieldLabel.Text = 'min r ';
            app.minrEditField = uieditfield(app.Panel_6, 'numeric');
            app.Panel_2 = uipanel(app.Panel_6);
            app.WellDepthEditFieldLabel = uilabel(app.Panel_2);
            app.WellDepthEditFieldLabel.Text = 'Well Depth';
            app.WellDepthEditField = uieditfield(app.Panel_2, 'numeric');
            app.SigmaEditFieldLabel = uilabel(app.Panel_2);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.Panel_2, 'numeric');
            app.rstepEditFieldLabel = uilabel(app.Panel_6);
            app.rstepEditFieldLabel.Text = 'r step';
            app.rstepEditField = uieditfield(app.Panel_6, 'numeric');
            app.Panel = uipanel(app.Panel_6);
            app.Z1EditFieldLabel = uilabel(app.Panel);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.Panel, 'numeric');
            app.Z2EditFieldLabel = uilabel(app.Panel);
            app.Z2EditFieldLabel.Text = 'Z2';
            app.Z2EditField = uieditfield(app.Panel, 'numeric');
            app.Panel_18 = uipanel(app.Panel_6);
            app.aEditFieldLabel = uilabel(app.Panel_18);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_18, 'numeric');
            app.kAngstrom1Label = uilabel(app.Panel_18);
            app.kAngstrom1Label.Text = 'k ';
            app.kEditField = uieditfield(app.Panel_18, 'numeric');
            app.Panel_13 = uipanel(app.Panel_12);
            app.Panel_13.Visible = 'off';
            app.toleranceEditField_2Label = uilabel(app.Panel_13);
            app.toleranceEditField_2Label.Text = 'tolerance';
            app.toleranceEditField_2 = uieditfield(app.Panel_13, 'numeric');
            app.minrEditField_3Label = uilabel(app.Panel_13);
            app.minrEditField_3Label.Text = 'min r ';
            app.minrEditField_3 = uieditfield(app.Panel_13, 'numeric');
            app.maxrEditField_3Label = uilabel(app.Panel_13);
            app.maxrEditField_3Label.Text = 'max r';
            app.maxrEditField_3 = uieditfield(app.Panel_13, 'numeric');
            app.SelectPotentialFileButton_2 = uibutton(app.Panel_13, 'push');
            app.SelectPotentialFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectPotentialFileButton_2Pushed, true);
            app.SelectPotentialFileButton_2.Text = 'Select Potential File';
            app.SelectedPathEditField_14Label = uilabel(app.Panel_13);
            app.SelectedPathEditField_14Label.Text = 'Selected Path';
            app.SelectedPathEditField_14 = uieditfield(app.Panel_13, 'text');
            app.Panel_14 = uipanel(app.Panel_13);
            app.SpecifyCoulombZBLParametersLabel = uilabel(app.Panel_14);
            app.SpecifyCoulombZBLParametersLabel.Text = 'Specify Coulomb/ZBL Parameters';
            app.Z1EditField_3Label = uilabel(app.Panel_14);
            app.Z1EditField_3Label.Text = 'Z1';
            app.Z1EditField_3 = uieditfield(app.Panel_14, 'numeric');
            app.maxZ2EditField_2Label = uilabel(app.Panel_14);
            app.maxZ2EditField_2Label.Text = 'max Z2';
            app.maxZ2EditField_2 = uieditfield(app.Panel_14, 'numeric');
            app.minZ2EditField_2Label = uilabel(app.Panel_14);
            app.minZ2EditField_2Label.Text = 'min Z2';
            app.minZ2EditField_2 = uieditfield(app.Panel_14, 'numeric');
            app.Panel_15 = uipanel(app.Panel_13);
            app.SpecifyLJParametersLabel_3 = uilabel(app.Panel_15);
            app.SpecifyLJParametersLabel_3.Text = 'Specify LJ Parameters';
            app.minwelldepthEditField_2Label = uilabel(app.Panel_15);
            app.minwelldepthEditField_2Label.Text = 'min well depth';
            app.minwelldepthEditField_2 = uieditfield(app.Panel_15, 'numeric');
            app.maxwelldepthEditField_2Label = uilabel(app.Panel_15);
            app.maxwelldepthEditField_2Label.Text = 'max well depth';
            app.maxwelldepthEditField_2 = uieditfield(app.Panel_15, 'numeric');
            app.minsigmaEditField_2Label = uilabel(app.Panel_15);
            app.minsigmaEditField_2Label.Text = 'min sigma';
            app.minsigmaEditField_2 = uieditfield(app.Panel_15, 'numeric');
            app.maxsigmaEditField_2Label = uilabel(app.Panel_15);
            app.maxsigmaEditField_2Label.Text = 'max sigma';
            app.maxsigmaEditField_2 = uieditfield(app.Panel_15, 'numeric');
            app.welldepthguessEditField_2Label = uilabel(app.Panel_15);
            app.welldepthguessEditField_2Label.Text = 'well depth guess';
            app.welldepthguessEditField_2 = uieditfield(app.Panel_15, 'numeric');
            app.sigmaguessEditField_2Label = uilabel(app.Panel_15);
            app.sigmaguessEditField_2Label.Text = 'sigma guess';
            app.sigmaguessEditField_2 = uieditfield(app.Panel_15, 'numeric');
            app.Panel_19 = uipanel(app.Panel_13);
            app.Panel_19.Visible = 'off';
            app.SpecifyPowerLawParametersLabel = uilabel(app.Panel_19);
            app.SpecifyPowerLawParametersLabel.Text = 'Specify Power Law Parameters';
            app.minaEditFieldLabel = uilabel(app.Panel_19);
            app.minaEditFieldLabel.Text = 'min a';
            app.minaEditField = uieditfield(app.Panel_19, 'numeric');
            app.maxaEditFieldLabel = uilabel(app.Panel_19);
            app.maxaEditFieldLabel.Text = 'max a';
            app.maxaEditField = uieditfield(app.Panel_19, 'numeric');
            app.minkEditFieldLabel = uilabel(app.Panel_19);
            app.minkEditFieldLabel.Text = 'min k';
            app.minkEditField = uieditfield(app.Panel_19, 'numeric');
            app.maxkEditFieldLabel = uilabel(app.Panel_19);
            app.maxkEditFieldLabel.Text = 'max k';
            app.maxkEditField = uieditfield(app.Panel_19, 'numeric');
            app.aguessEditFieldLabel = uilabel(app.Panel_19);
            app.aguessEditFieldLabel.Text = 'a guess';
            app.aguessEditField = uieditfield(app.Panel_19, 'numeric');
            app.kguessEditFieldLabel = uilabel(app.Panel_19);
            app.kguessEditFieldLabel.Text = 'k guess';
            app.kguessEditField = uieditfield(app.Panel_19, 'numeric');
            app.CalculateScatteringIntegralsTab = uitab(app.TabGroup);
            app.CalculateScatteringIntegralsTab.Title = 'Calculate Scattering Integrals';
            app.Panel_7 = uipanel(app.CalculateScatteringIntegralsTab);
            app.minEeVEditFieldLabel = uilabel(app.Panel_7);
            app.minEeVEditFieldLabel.Text = 'min E (eV)';
            app.minEeVEditField = uieditfield(app.Panel_7, 'numeric');
            app.ScatteringIntegralsLabel = uilabel(app.Panel_7);
            app.ScatteringIntegralsLabel.Text = 'Scattering Integrals';
            app.maxEeVEditFieldLabel = uilabel(app.Panel_7);
            app.maxEeVEditFieldLabel.Text = 'max E (eV)';
            app.maxEeVEditField = uieditfield(app.Panel_7, 'numeric');
            app.EstepeVEditFieldLabel = uilabel(app.Panel_7);
            app.EstepeVEditFieldLabel.Text = 'E step (eV)';
            app.EstepeVEditField = uieditfield(app.Panel_7, 'numeric');
            app.Panel_17 = uipanel(app.Panel_7);
            app.z1EditFieldLabel = uilabel(app.Panel_17);
            app.z1EditFieldLabel.Text = 'z1';
            app.z1EditField = uieditfield(app.Panel_17, 'numeric');
            app.z2EditFieldLabel = uilabel(app.Panel_17);
            app.z2EditFieldLabel.Text = 'z2';
            app.z2EditField = uieditfield(app.Panel_17, 'numeric');
            app.thetamaxradEditFieldLabel = uilabel(app.Panel_17);
            app.thetamaxradEditFieldLabel.Text = 'theta max (rad)';
            app.thetamaxradEditField = uieditfield(app.Panel_17, 'numeric');
            app.thetastepradEditFieldLabel = uilabel(app.Panel_17);
            app.thetastepradEditFieldLabel.Text = 'theta step (rad)';
            app.thetastepradEditField = uieditfield(app.Panel_17, 'numeric');
            app.thetaminradEditFieldLabel = uilabel(app.Panel_17);
            app.thetaminradEditFieldLabel.Text = 'theta min (rad)';
            app.thetaminradEditField = uieditfield(app.Panel_17, 'numeric');
            app.Panel_16 = uipanel(app.Panel_7);
            app.SpecifyFitOutputFileLabel = uilabel(app.Panel_16);
            app.SpecifyFitOutputFileLabel.Text = 'Specify Fit Output File';
            app.SelectFileButton = uibutton(app.Panel_16, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.SelectedPathEditField_15Label = uilabel(app.Panel_16);
            app.SelectedPathEditField_15Label.Text = 'Selected Path';
            app.SelectedPathEditField_15 = uieditfield(app.Panel_16, 'text');
            app.bminAngEditFieldLabel = uilabel(app.Panel_16);
            app.bminAngEditFieldLabel.Text = 'b min (Ang)';
            app.bminAngEditField = uieditfield(app.Panel_16, 'numeric');
            app.bmaxAngEditFieldLabel = uilabel(app.Panel_16);
            app.bmaxAngEditFieldLabel.Text = 'b max (Ang)';
            app.bmaxAngEditField = uieditfield(app.Panel_16, 'numeric');
            app.bstepAngEditFieldLabel = uilabel(app.Panel_16);
            app.bstepAngEditFieldLabel.Text = 'b step (Ang)';
            app.bstepAngEditField = uieditfield(app.Panel_16, 'numeric');
            app.SpecifyRootFindingRangeforRminLabel = uilabel(app.Panel_16);
            app.SpecifyRootFindingRangeforRminLabel.Text = 'Specify Root Finding Range for Rmin';
            app.minRminEditFieldLabel = uilabel(app.Panel_16);
            app.minRminEditFieldLabel.Text = 'min Rmin';
            app.minRminEditField = uieditfield(app.Panel_16, 'numeric');
            app.minRminEditField.ValueChangedFcn = createCallbackFcn(app, @minRminEditFieldValueChanged, true);
            app.maxRminEditFieldLabel = uilabel(app.Panel_16);
            app.maxRminEditFieldLabel.Text = 'max Rmin';
            app.maxRminEditField = uieditfield(app.Panel_16, 'numeric');
            app.ExecuteButton_3 = uibutton(app.CalculateScatteringIntegralsTab, 'push');
            app.ExecuteButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_3Pushed, true);
            app.ExecuteButton_3.Text = 'Execute';
            app.TabGroup2_3 = uitabgroup(app.CalculateScatteringIntegralsTab);
            app.DistofClosestApproachTab = uitab(app.TabGroup2_3);
            app.DistofClosestApproachTab.Title = 'Dist. of Closest Approach';
            app.UIAxes_7 = uiaxes(app.DistofClosestApproachTab);
            title(app.UIAxes_7, 'Distance of Closest Approach')
            xlabel(app.UIAxes_7, 'Impact Parameter (Angstrom)')
            ylabel(app.UIAxes_7, 'Distance (Angstrom)')
            zlabel(app.UIAxes_7, 'Z')
            app.ScatteringTab = uitab(app.TabGroup2_3);
            app.ScatteringTab.Title = 'Scattering';
            app.UIAxes_5 = uiaxes(app.ScatteringTab);
            title(app.UIAxes_5, 'Differential Scattering Cross Section')
            xlabel(app.UIAxes_5, 'Scattering Angle (rad)')
            ylabel(app.UIAxes_5, 'Cross Section (Angstrom^2/sr)')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.ButtonDownFcn = createCallbackFcn(app, @UIAxes_5ButtonDown, true);
            app.ImpactParameterTab = uitab(app.TabGroup2_3);
            app.ImpactParameterTab.Title = 'Impact Parameter';
            app.UIAxes_6 = uiaxes(app.ImpactParameterTab);
            title(app.UIAxes_6, 'Impact Parameter')
            xlabel(app.UIAxes_6, 'Scattering Angle (rad)')
            ylabel(app.UIAxes_6, 'Impact Parameter (Angstrom)')
            zlabel(app.UIAxes_6, 'Z')
            app.SelectPathButton = uibutton(app.CalculateScatteringIntegralsTab, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'Select Path';
            app.SpecifyWheretoSaveDifferentialScatteringDataLabel = uilabel(app.CalculateScatteringIntegralsTab);
            app.SpecifyWheretoSaveDifferentialScatteringDataLabel.Text = 'Specify Where to Save Differential Scattering Data';
            app.SelectedPathEditField_5Label = uilabel(app.CalculateScatteringIntegralsTab);
            app.SelectedPathEditField_5Label.Text = 'Selected Path';
            app.SelectedPathEditField_5 = uieditfield(app.CalculateScatteringIntegralsTab, 'text');
            app.WriteScatteringInputFileButton = uibutton(app.CalculateScatteringIntegralsTab, 'push');
            app.WriteScatteringInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteScatteringInputFileButtonPushed, true);
            app.WriteScatteringInputFileButton.Text = 'Write Scattering Input File';
            app.OrselectyourownscatteringinputfileLabel = uilabel(app.CalculateScatteringIntegralsTab);
            app.OrselectyourownscatteringinputfileLabel.Text = 'Or select your own scattering input file';
            app.SelectScatteringInputFileButton = uibutton(app.CalculateScatteringIntegralsTab, 'push');
            app.SelectScatteringInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectScatteringInputFileButtonPushed, true);
            app.SelectScatteringInputFileButton.Text = 'Select Scattering Input File';
            app.SelectedFileEditFieldLabel = uilabel(app.CalculateScatteringIntegralsTab);
            app.SelectedFileEditFieldLabel.Text = 'Selected File';
            app.SelectedFileEditField = uieditfield(app.CalculateScatteringIntegralsTab, 'text');
            app.SpecifyWheretoSaveInputFileLabel_2 = uilabel(app.CalculateScatteringIntegralsTab);
            app.SpecifyWheretoSaveInputFileLabel_2.Text = 'Specify Where to Save Input File';
            app.SelectedPathEditField_6Label = uilabel(app.CalculateScatteringIntegralsTab);
            app.SelectedPathEditField_6Label.Text = 'Selected Path';
            app.SelectedPathEditField_6 = uieditfield(app.CalculateScatteringIntegralsTab, 'text');
            app.SelectPathButton_2 = uibutton(app.CalculateScatteringIntegralsTab, 'push');
            app.SelectPathButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButton_2Pushed, true);
            app.SelectPathButton_2.Text = 'Select Path';
            app.IntegralTypeDropDownLabel = uilabel(app.CalculateScatteringIntegralsTab);
            app.IntegralTypeDropDownLabel.Text = 'Integral Type';
            app.IntegralTypeDropDown = uidropdown(app.CalculateScatteringIntegralsTab);
            app.IntegralTypeDropDown.Items = {'Select Integral Type', 'Exact Coulomb', 'Numerical'};
            app.IntegralTypeDropDown.ClickedFcn = createCallbackFcn(app, @IntegralTypeDropDownClicked, true);
            app.IntegralTypeDropDown.Value = 'Select Integral Type';
            app.CalculateTransportCrossSectionsTab = uitab(app.TabGroup);
            app.CalculateTransportCrossSectionsTab.Title = 'Calculate Transport Cross Sections';
            app.TabGroup2_4 = uitabgroup(app.CalculateTransportCrossSectionsTab);
            app.DiffusionCrossSectionTab = uitab(app.TabGroup2_4);
            app.DiffusionCrossSectionTab.Title = 'Diffusion Cross Section';
            app.UIAxes_8 = uiaxes(app.DiffusionCrossSectionTab);
            title(app.UIAxes_8, 'Diffusion Cross Section')
            xlabel(app.UIAxes_8, 'Energy')
            zlabel(app.UIAxes_8, 'Z')
            app.ViscosityCrossSectionTab = uitab(app.TabGroup2_4);
            app.ViscosityCrossSectionTab.Title = 'Viscosity Cross Section';
            app.UIAxes_9 = uiaxes(app.ViscosityCrossSectionTab);
            title(app.UIAxes_9, 'Viscosity Cross Section')
            xlabel(app.UIAxes_9, 'Scattering Parameter')
            zlabel(app.UIAxes_9, 'Z')
            app.Panel_8 = uipanel(app.CalculateTransportCrossSectionsTab);
            app.minEeVEditField_2Label = uilabel(app.Panel_8);
            app.minEeVEditField_2Label.Text = 'min E (eV)';
            app.minEeVEditField_2 = uieditfield(app.Panel_8, 'numeric');
            app.TransportCrossSectionLabel = uilabel(app.Panel_8);
            app.TransportCrossSectionLabel.Text = 'Transport Cross Section';
            app.maxEeVEditField_2Label = uilabel(app.Panel_8);
            app.maxEeVEditField_2Label.Text = 'max E (eV)';
            app.maxEeVEditField_2 = uieditfield(app.Panel_8, 'numeric');
            app.EstepeVEditField_2Label = uilabel(app.Panel_8);
            app.EstepeVEditField_2Label.Text = 'E step (eV)';
            app.EstepeVEditField_2 = uieditfield(app.Panel_8, 'numeric');
            app.Panel_20 = uipanel(app.Panel_8);
            app.welldeptheVEditFieldLabel = uilabel(app.Panel_20);
            app.welldeptheVEditFieldLabel.Text = 'well depth (eV)';
            app.welldeptheVEditField = uieditfield(app.Panel_20, 'numeric');
            app.Panel_21 = uipanel(app.Panel_8);
            app.SpecifyImpactParamvsScatterAngleDataFolderLabel = uilabel(app.Panel_21);
            app.SpecifyImpactParamvsScatterAngleDataFolderLabel.Text = 'Specify Impact Param vs Scatter Angle Data Folder';
            app.SelectPathButton_7 = uibutton(app.Panel_21, 'push');
            app.SelectPathButton_7.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButton_7Pushed, true);
            app.SelectPathButton_7.Text = 'Select Path';
            app.SelectedPathEditField_16Label = uilabel(app.Panel_21);
            app.SelectedPathEditField_16Label.Text = 'Selected Path';
            app.SelectedPathEditField_16 = uieditfield(app.Panel_21, 'text');
            app.ExecuteButton_4 = uibutton(app.CalculateTransportCrossSectionsTab, 'push');
            app.ExecuteButton_4.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_4Pushed, true);
            app.ExecuteButton_4.Text = 'Execute';
            app.SelectPathButton_3 = uibutton(app.CalculateTransportCrossSectionsTab, 'push');
            app.SelectPathButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButton_3Pushed, true);
            app.SelectPathButton_3.Text = 'Select Path';
            app.SpecifyWheretoSaveTransportCrossSectionDataLabel = uilabel(app.CalculateTransportCrossSectionsTab);
            app.SpecifyWheretoSaveTransportCrossSectionDataLabel.Text = 'Specify Where to Save Transport Cross Section Data';
            app.WriteTransportInputFileButton = uibutton(app.CalculateTransportCrossSectionsTab, 'push');
            app.WriteTransportInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteTransportInputFileButtonPushed, true);
            app.WriteTransportInputFileButton.Text = 'Write Transport Input File';
            app.OrselectyourowntransportinputfileLabel = uilabel(app.CalculateTransportCrossSectionsTab);
            app.OrselectyourowntransportinputfileLabel.Text = 'Or select your own transport input file';
            app.SelectTransportInputFileButton = uibutton(app.CalculateTransportCrossSectionsTab, 'push');
            app.SelectTransportInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTransportInputFileButtonPushed, true);
            app.SelectTransportInputFileButton.Text = 'Select Transport Input File';
            app.SpecifyWheretoSaveInputFileLabel_3 = uilabel(app.CalculateTransportCrossSectionsTab);
            app.SpecifyWheretoSaveInputFileLabel_3.Text = 'Specify Where to Save Input File';
            app.SelectPathButton_4 = uibutton(app.CalculateTransportCrossSectionsTab, 'push');
            app.SelectPathButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButton_4Pushed, true);
            app.SelectPathButton_4.Text = 'Select Path';
            app.SelectedPathEditField_7Label = uilabel(app.CalculateTransportCrossSectionsTab);
            app.SelectedPathEditField_7Label.Text = 'Selected Path';
            app.SelectedPathEditField_7 = uieditfield(app.CalculateTransportCrossSectionsTab, 'text');
            app.SelectedFileEditField_2Label = uilabel(app.CalculateTransportCrossSectionsTab);
            app.SelectedFileEditField_2Label.Text = 'Selected File';
            app.SelectedFileEditField_2 = uieditfield(app.CalculateTransportCrossSectionsTab, 'text');
            app.SelectedPathEditField_8Label = uilabel(app.CalculateTransportCrossSectionsTab);
            app.SelectedPathEditField_8Label.Text = 'Selected Path';
            app.SelectedPathEditField_8 = uieditfield(app.CalculateTransportCrossSectionsTab, 'text');
            app.IntegralTypeDropDown_2Label = uilabel(app.CalculateTransportCrossSectionsTab);
            app.IntegralTypeDropDown_2Label.Text = 'Integral Type';
            app.IntegralTypeDropDown_2 = uidropdown(app.CalculateTransportCrossSectionsTab);
            app.IntegralTypeDropDown_2.Items = {'Select Integral Type', 'Exact LJ', 'Numerical'};
            app.IntegralTypeDropDown_2.ClickedFcn = createCallbackFcn(app, @IntegralTypeDropDown_2Clicked, true);
            app.IntegralTypeDropDown_2.Value = 'Select Integral Type';
            app.CalcuateTransportCoefficientsTab = uitab(app.TabGroup);
            app.CalcuateTransportCoefficientsTab.Title = 'Calcuate Transport Coefficients';
            app.Panel_9 = uipanel(app.CalcuateTransportCoefficientsTab);
            app.minTKEditFieldLabel = uilabel(app.Panel_9);
            app.minTKEditFieldLabel.Text = 'min T (K)';
            app.minTKEditField = uieditfield(app.Panel_9, 'numeric');
            app.maxTKEditFieldLabel = uilabel(app.Panel_9);
            app.maxTKEditFieldLabel.Text = 'max T (K)';
            app.maxTKEditField = uieditfield(app.Panel_9, 'numeric');
            app.TstepKEditFieldLabel = uilabel(app.Panel_9);
            app.TstepKEditFieldLabel.Text = 'T step (K)';
            app.TstepKEditField = uieditfield(app.Panel_9, 'numeric');
            app.welldepthKEditFieldLabel = uilabel(app.Panel_9);
            app.welldepthKEditFieldLabel.Text = 'well depth (K)';
            app.welldepthKEditField = uieditfield(app.Panel_9, 'numeric');
            app.m1amuEditFieldLabel = uilabel(app.Panel_9);
            app.m1amuEditFieldLabel.Text = 'm1 (amu)';
            app.m1amuEditField = uieditfield(app.Panel_9, 'numeric');
            app.m2amuEditFieldLabel = uilabel(app.Panel_9);
            app.m2amuEditFieldLabel.Text = 'm2 (amu)';
            app.m2amuEditField = uieditfield(app.Panel_9, 'numeric');
            app.Panel_10 = uipanel(app.Panel_9);
            app.PressurebarEditFieldLabel = uilabel(app.Panel_10);
            app.PressurebarEditFieldLabel.Text = 'Pressure (bar)';
            app.PressurebarEditField = uieditfield(app.Panel_10, 'numeric');
            app.dangstromEditFieldLabel = uilabel(app.Panel_9);
            app.dangstromEditFieldLabel.Text = 'd (angstrom)';
            app.dangstromEditField = uieditfield(app.Panel_9, 'numeric');
            app.ExecuteButton_5 = uibutton(app.CalcuateTransportCoefficientsTab, 'push');
            app.ExecuteButton_5.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_5Pushed, true);
            app.ExecuteButton_5.Text = 'Execute';
            app.SelectPathButton_5 = uibutton(app.CalcuateTransportCoefficientsTab, 'push');
            app.SelectPathButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButton_5Pushed, true);
            app.SelectPathButton_5.Text = 'Select Path';
            app.SpecifyWheretoSaveTransportCoefficientDataLabel = uilabel(app.CalcuateTransportCoefficientsTab);
            app.SpecifyWheretoSaveTransportCoefficientDataLabel.Text = 'Specify Where to Save Transport Coefficient Data';
            app.WriteTransportInputFileButton_2 = uibutton(app.CalcuateTransportCoefficientsTab, 'push');
            app.WriteTransportInputFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @WriteTransportInputFileButton_2Pushed, true);
            app.WriteTransportInputFileButton_2.Text = 'Write Transport Input File';
            app.OrselectyourowntransportinputfileLabel_2 = uilabel(app.CalcuateTransportCoefficientsTab);
            app.OrselectyourowntransportinputfileLabel_2.Text = 'Or select your own transport input file';
            app.SelectTransportInputFileButton_2 = uibutton(app.CalcuateTransportCoefficientsTab, 'push');
            app.SelectTransportInputFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectTransportInputFileButton_2Pushed, true);
            app.SelectTransportInputFileButton_2.Text = 'Select Transport Input File';
            app.SpecifyWheretoSaveInputFileLabel_4 = uilabel(app.CalcuateTransportCoefficientsTab);
            app.SpecifyWheretoSaveInputFileLabel_4.Text = 'Specify Where to Save Input File';
            app.SelectPathButton_6 = uibutton(app.CalcuateTransportCoefficientsTab, 'push');
            app.SelectPathButton_6.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButton_6Pushed, true);
            app.SelectPathButton_6.Text = 'Select Path';
            app.SelectedPathEditField_9Label = uilabel(app.CalcuateTransportCoefficientsTab);
            app.SelectedPathEditField_9Label.Text = 'Selected Path';
            app.SelectedPathEditField_9 = uieditfield(app.CalcuateTransportCoefficientsTab, 'text');
            app.SelectedFileEditField_3Label = uilabel(app.CalcuateTransportCoefficientsTab);
            app.SelectedFileEditField_3Label.Text = 'Selected File';
            app.SelectedFileEditField_3 = uieditfield(app.CalcuateTransportCoefficientsTab, 'text');
            app.SelectedPathEditField_10Label = uilabel(app.CalcuateTransportCoefficientsTab);
            app.SelectedPathEditField_10Label.Text = 'Selected Path';
            app.SelectedPathEditField_10 = uieditfield(app.CalcuateTransportCoefficientsTab, 'text');
            app.TabGroup2_5 = uitabgroup(app.CalcuateTransportCoefficientsTab);
            app.LINEARTab_3 = uitab(app.TabGroup2_5);
            app.LINEARTab_3.Title = 'LINEAR';
            app.UIAxes_10 = uiaxes(app.LINEARTab_3);
            title(app.UIAxes_10, 'Transport Coefficient')
            xlabel(app.UIAxes_10, 'Temperature (K)')
            zlabel(app.UIAxes_10, 'Z')
            app.LOGTab_3 = uitab(app.TabGroup2_5);
            app.LOGTab_3.Title = 'LOG';
            app.UIAxes_11 = uiaxes(app.LOGTab_3);
            title(app.UIAxes_11, 'Transport Coefficient')
            xlabel(app.UIAxes_11, 'Temperature (K)')
            zlabel(app.UIAxes_11, 'Z')
            app.TransportCoefficientDropDownLabel = uilabel(app.CalcuateTransportCoefficientsTab);
            app.TransportCoefficientDropDownLabel.Text = 'Transport Coefficient';
            app.TransportCoefficientDropDown = uidropdown(app.CalcuateTransportCoefficientsTab);
            app.TransportCoefficientDropDown.Items = {'Select Transport Coefficient', 'Self-Diffusion', 'Viscosity'};
            app.TransportCoefficientDropDown.ClickedFcn = createCallbackFcn(app, @TransportCoefficientDropDownClicked, true);
            app.TransportCoefficientDropDown.Value = 'Select Transport Coefficient';
            app.IntegrationTypeDropDownLabel = uilabel(app.CalcuateTransportCoefficientsTab);
            app.IntegrationTypeDropDownLabel.Text = 'Integration Type';
            app.IntegrationTypeDropDown = uidropdown(app.CalcuateTransportCoefficientsTab);
            app.IntegrationTypeDropDown.Items = {'Select Integration Type', 'Exact', 'Trapezoidal'};
            app.IntegrationTypeDropDown.ClickedFcn = createCallbackFcn(app, @IntegrationTypeDropDownClicked, true);
            app.IntegrationTypeDropDown.Value = 'Select Integration Type';
            app.Panel_11 = uipanel(app.CalcuateTransportCoefficientsTab);
            app.SelectCrossSectionDataButton = uibutton(app.Panel_11, 'push');
            app.SelectCrossSectionDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCrossSectionDataButtonPushed, true);
            app.SelectCrossSectionDataButton.Text = 'Select Cross Section Data';
            app.SelectedFileEditField_4Label = uilabel(app.Panel_11);
            app.SelectedFileEditField_4Label.Text = 'Selected File';
            app.SelectedFileEditField_4 = uieditfield(app.Panel_11, 'text');
            app.CalcuateDSMCCoefficientsTab = uitab(app.TabGroup);
            app.CalcuateDSMCCoefficientsTab.Title = 'Calcuate DSMC Coefficients';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end